classdef OrbitApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ClearButton                 matlab.ui.control.StateButton
        EccentricityEditField       matlab.ui.control.NumericEditField
        EccentricityEditFieldLabel  matlab.ui.control.Label
        InclinationEditField        matlab.ui.control.NumericEditField
        InclinationEditFieldLabel   matlab.ui.control.Label
        InputButton                 matlab.ui.control.StateButton
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: InputButton
        function InputButtonValueChanged(app, event)
            buttonval = app.InputButton.Value;
            disp(buttonval)
            if buttonval == 1
                Re = 6378;
                inc = app.InclinationEditField.Value;
                disp(inc)
                e = app.EccentricityEditField.Value;
                disp(e)
                [Xp] = Simulation(e,inc);
                plot3(Xp(:,1), Xp(:,2), Xp(:,3),'g-','Parent',app.UIAxes)
                hold(app.UIAxes)
                [XS, YS, ZS] = sphere(15);
                surf(app.UIAxes,XS*Re, YS*Re, ZS*Re);
                axis([-1.5e4, 1.5e4, -1.5e4, 1.5e4, -1.5e4, 1.5e4])
                hold off;
                hold(app.UIAxes,'on')
                    
            else
                Re = 0;
                inc = app.InclinationEditField.Value;
                disp(inc)
                e = app.EccentricityEditField.Value;
                disp(e)
                [Xp] = Simulation(e,inc);
                plot3(Xp(:,1), Xp(:,2), Xp(:,3),'g-','Parent',app.UIAxes)
                hold(app.UIAxes)
                [XS, YS, ZS] = sphere(15);
                surf(app.UIAxes,XS*Re, YS*Re, ZS*Re);
                axis([-1.5e4, 1.5e4, -1.5e4, 1.5e4, -1.5e4, 1.5e4])
                hold off;
            end
        end

        % Value changed function: ClearButton
        function ClearButtonValueChanged(app, event)
            cla(app.UIAxes)
            app.InclinationEditField.Value = 0;
            app.EccentricityEditField.Value = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Satellite Orbit Visualization')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.InputButton = uibutton(app.UIFigure, 'state');
            app.InputButton.ValueChangedFcn = createCallbackFcn(app, @InputButtonValueChanged, true);
            app.InputButton.Text = 'Input';
            app.InclinationEditFieldLabel = uilabel(app.UIFigure);
            app.InclinationEditFieldLabel.Text = 'Inclination';
            app.InclinationEditField = uieditfield(app.UIFigure, 'numeric');
            app.EccentricityEditFieldLabel = uilabel(app.UIFigure);
            app.EccentricityEditFieldLabel.Text = 'Eccentricity';
            app.EccentricityEditField = uieditfield(app.UIFigure, 'numeric');
            app.ClearButton = uibutton(app.UIFigure, 'state');
            app.ClearButton.ValueChangedFcn = createCallbackFcn(app, @ClearButtonValueChanged, true);
            app.ClearButton.Text = 'Clear';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OrbitApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end